classdef AudioSignalGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ResetButton              matlab.ui.control.Button
        FileLabel                matlab.ui.control.Label
        StatusLabel              matlab.ui.control.Label
        ApplyFilterButton        matlab.ui.control.Button
        FilterTypeDropDown       matlab.ui.control.DropDown
        FilterTypeDropDownLabel  matlab.ui.control.Label
        DurationsLabel           matlab.ui.control.Label
        FsHzLabel                matlab.ui.control.Label
        PlayFilteredButton       matlab.ui.control.Button
        PlayOriginalButton       matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        UIAxes3_2                matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Data container
    app.UIFigure.UserData = struct();
    app.UIFigure.UserData.x  = [];
    app.UIFigure.UserData.y  = [];
    app.UIFigure.UserData.Fs = [];
    app.UIFigure.UserData.t  = [];
    app.PlayOriginalButton.Enable = 'off';
    app.ApplyFilterButton.Enable = 'off';
    app.PlayFilteredButton.Enable = 'off';
    % Dropdown defaults
    app.FilterTypeDropDown.Items = {'Low-pass','Band-pass','High-pass','Custom Band-pass Note2'};
    app.FilterTypeDropDown.Value = 'Low-pass';

    % Clear axes (senin axes isimlerin!)
    cla(app.UIAxes);     % Original time
    cla(app.UIAxes2);    % Original FFT
    cla(app.UIAxes3);    % Filtered time
    cla(app.UIAxes3_2);  % Filtered FFT

    % Labels (senin label isimlerin!)
    app.FsHzLabel.Text = 'Fs: --- Hz';
    app.DurationsLabel.Text = 'Duration: --- s';
    app.StatusLabel.Text = ' Status : Ready';
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
 [file, path] = uigetfile({'*.wav;*.mp3;*.m4a;*.mp4','Audio Files (*.wav,*.mp3,*.m4a,*.mp4)'}, ...
                             'Select an audio file');
    if isequal(file,0); return; end

    [x, Fs] = audioread(fullfile(path,file));

    if size(x,2) > 1
        x = mean(x,2);
    end

    N = length(x);
    t = (0:N-1)/Fs;

    app.UIFigure.UserData.x = x;
    app.UIFigure.UserData.Fs = Fs;
    app.UIFigure.UserData.t = t;
    app.UIFigure.UserData.y = [];
    app.PlayOriginalButton.Enable = 'on';
    app.ApplyFilterButton.Enable = 'on';
    app.PlayFilteredButton.Enable = 'off';

    app.FsHzLabel.Text = sprintf('Fs: %.0f Hz', Fs);
    app.DurationsLabel.Text = sprintf('Duration: %.2f s', N/Fs);
    app.StatusLabel.Text = "Status: Loaded ✔";
    app.FileLabel.Text = "File: " + string(file);


    % Original time -> app.UIAxes
    plot(app.UIAxes, t, x);
    grid(app.UIAxes,'on');
    xlabel(app.UIAxes,'Time (s)');
    ylabel(app.UIAxes,'Amplitude');
    title(app.UIAxes,'Original Signal (Time Domain)');

    % Original spectrum -> app.UIAxes2
X = fft(x);
f = (0:N-1)*(Fs/N);

mag = abs(X)/N;
mag_dB = 20*log10(mag + 1e-12);   % <-- dB dönüşüm

plot(app.UIAxes2, f(1:floor(N/2)), mag_dB(1:floor(N/2)));
grid(app.UIAxes2,'on');
xlabel(app.UIAxes2,'Frequency (Hz)');
ylabel(app.UIAxes2,'Magnitude (dB)');
title(app.UIAxes2,'Original Signal (Spectrum)');


    cla(app.UIAxes3);
    cla(app.UIAxes3_2);

        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
             x = app.UIFigure.UserData.x;
    Fs = app.UIFigure.UserData.Fs;

    if isempty(x) || isempty(Fs)
        uialert(app.UIFigure,'Önce Load Audio yap.','No Audio');
        return;
    end

    soundsc(x, Fs);
        end

        % Button pushed function: PlayFilteredButton
        function PlayFilteredButtonPushed(app, event)
    y  = app.UIFigure.UserData.y;
    Fs = app.UIFigure.UserData.Fs;

    if isempty(y) || isempty(Fs)
        uialert(app.UIFigure,'Önce filtreyi uygula (Apply Filter).','No Filtered Audio');
        return;
    end

    soundsc(y, Fs);

        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            x  = app.UIFigure.UserData.x;
    Fs = app.UIFigure.UserData.Fs;
    t  = app.UIFigure.UserData.t;

    if isempty(x)
        uialert(app.UIFigure,'Önce Load Audio yap.','No Audio');
        return;
    end

    ftype = app.FilterTypeDropDown.Value;
    nyq = Fs/2;

    % Geçici sabit ayarlar (sonra edit field ekleriz)
    Nf = 4;
    flow  = 400;   % Hz
    fhigh = 500;   % Hz
  
    switch ftype
        case 'Low-pass'
            Wn = min(fhigh/nyq,0.99);
            [b,a] = butter(Nf, Wn, 'low');
             

        case 'High-pass'
            Wn = min(flow/nyq,0.99);
            [b,a] = butter(Nf, Wn, 'high');
        

        case 'Band-pass'
            Wn = [flow fhigh]/nyq;
            [b,a] = butter(Nf, Wn, 'bandpass');
           

        case 'Custom Band-pass Note2'
    % ===== EXACT HOMEWORK FILTER =====
    f0_2 = 293.68;    % measured fundamental of note2 (Hz)
    Df   = 20;        % bandwidth half-width (Hz)
    n    = 4;         % filter order

    fc1 = f0_2 - Df;
    fc2 = f0_2 + Df;

    Wn = [fc1 fc2] / (Fs/2);
    [b,a] = butter(n, Wn, 'bandpass');
 

    end

    y = filter(b,a,x);
    app.UIFigure.UserData.y = y;

    % Filtered time (UIAxes3)
    plot(app.UIAxes3, t, y);
    grid(app.UIAxes3,'on');
    title(app.UIAxes3,'Filtered Signal (Time Domain)');
    xlabel(app.UIAxes3,'Time (s)'); ylabel(app.UIAxes3,'Amplitude');

    % Filtered spectrum (UIAxes3_2)
    N = length(y);
Y = fft(y);
f = (0:N-1)*(Fs/N);

mag = abs(Y)/N;
mag_dB = 20*log10(mag + 1e-12);   % <-- dB dönüşüm

plot(app.UIAxes3_2, f(1:floor(N/2)), mag_dB(1:floor(N/2)));
xlim(app.UIAxes3_2,[0 Fs/2]);
grid(app.UIAxes3_2,'on');
xlabel(app.UIAxes3_2,'Frequency (Hz)');
ylabel(app.UIAxes3_2,'Magnitude (dB)');
title(app.UIAxes3_2,'Filtered Signal (Spectrum)');

    app.PlayFilteredButton.Enable = "on";
    app.StatusLabel.Text = "Status: Filter applied ✔";
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
                app.UIFigure.UserData = struct('x',[],'y',[],'Fs',[],'t',[]);
    cla(app.UIAxes); cla(app.UIAxes2); cla(app.UIAxes3); cla(app.UIAxes3_2);
    app.FsHzLabel.Text = 'Fs: --- Hz';
    app.DurationsLabel.Text = 'Duration: --- s';
    app.StatusLabel.Text = "Status: Ready";
    app.PlayOriginalButton.Enable='off';
    app.ApplyFilterButton.Enable='off';
    app.PlayFilteredButton.Enable='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Signal (Time Domain)')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            colormap(app.UIAxes, 'summer')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Original Signal (Spectrum)')
            xlabel(app.UIAxes2, 'Frequeny (Hz)')
            ylabel(app.UIAxes2, 'Magnitude')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Filtered Signal (Time Domain)')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Amplitude')
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, 'Filtered Signal (Spectrum)')
            xlabel(app.UIAxes3_2, 'Frequency (Hz)')
            ylabel(app.UIAxes3_2, 'Magnitude')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Button';
            app.PlayOriginalButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'Play Original';
            app.PlayFilteredButton = uibutton(app.UIFigure, 'push');
            app.PlayFilteredButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredButtonPushed, true);
            app.PlayFilteredButton.Text = 'Play Filtered';
            app.FsHzLabel = uilabel(app.UIFigure);
            app.FsHzLabel.Text = 'Fs: --- Hz';
            app.DurationsLabel = uilabel(app.UIFigure);
            app.DurationsLabel.Text = 'Duration: --- s';
            app.FilterTypeDropDownLabel = uilabel(app.UIFigure);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.UIFigure);
            app.FilterTypeDropDown.Items = {'Low-pass', 'Band-pass', 'High-pass', 'Custom Band-pass Note2'};
            app.FilterTypeDropDown.Value = 'Custom Band-pass Note2';
            app.ApplyFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.FileLabel = uilabel(app.UIFigure);
            app.FileLabel.Text = 'File';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AudioSignalGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end